classdef MainApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SignalProcessorUIFigure  matlab.ui.Figure
        AddsignalMenu            matlab.ui.container.Menu
        CreateMenu               matlab.ui.container.Menu
        SyntheticMenu            matlab.ui.container.Menu
        SineMenu                 matlab.ui.container.Menu
        CosineMenu               matlab.ui.container.Menu
        ChirpMenu                matlab.ui.container.Menu
        StepMenu                 matlab.ui.container.Menu
        RampMenu                 matlab.ui.container.Menu
        SawtoothMenu             matlab.ui.container.Menu
        RecordaudioMenu          matlab.ui.container.Menu
        AudiofileMenu            matlab.ui.container.Menu
        CaptureMenu              matlab.ui.container.Menu
        SaveMenu                 matlab.ui.container.Menu
        Signal1Menu              matlab.ui.container.Menu
        ChannelMenu              matlab.ui.container.Menu
        OutMenu                  matlab.ui.container.Menu
        Result1Menu              matlab.ui.container.Menu
        AllMenu                  matlab.ui.container.Menu
        Signal2Menu              matlab.ui.container.Menu
        ChannelMenu_2            matlab.ui.container.Menu
        OutMenu_2                matlab.ui.container.Menu
        Result2Menu              matlab.ui.container.Menu
        AllMenu_2                matlab.ui.container.Menu
        StandardizationMenu      matlab.ui.container.Menu
        ViewMenu                 matlab.ui.container.Menu
        FrecuencyMenu            matlab.ui.container.Menu
        Signal1Menu_2            matlab.ui.container.Menu
        Signal2Menu_2            matlab.ui.container.Menu
        ResultsandFiltersMenu    matlab.ui.container.Menu
        GridLayout               matlab.ui.container.GridLayout
        TabGroup_2               matlab.ui.container.TabGroup
        Channel1Tab_2            matlab.ui.container.Tab
        GridLayout8_5            matlab.ui.container.GridLayout
        UIAxes3_5                matlab.ui.control.UIAxes
        Channel2Tab_2            matlab.ui.container.Tab
        GridLayout8_6            matlab.ui.container.GridLayout
        UIAxes3_6                matlab.ui.control.UIAxes
        Out1Tab_2                matlab.ui.container.Tab
        GridLayout8_7            matlab.ui.container.GridLayout
        UIAxes3_7                matlab.ui.control.UIAxes
        Out2Tab_2                matlab.ui.container.Tab
        GridLayout8_8            matlab.ui.container.GridLayout
        UIAxes3_8                matlab.ui.control.UIAxes
        Result2Tab               matlab.ui.container.Tab
        GridLayout10             matlab.ui.container.GridLayout
        UIAxes3_10               matlab.ui.control.UIAxes
        Panel_3                  matlab.ui.container.Panel
        GridLayout7              matlab.ui.container.GridLayout
        Signal2CheckBox          matlab.ui.control.CheckBox
        Signal1CheckBox          matlab.ui.control.CheckBox
        ApplyButton_3            matlab.ui.control.Button
        SetvolumeEditField       matlab.ui.control.EditField
        SetvolumeEditFieldLabel  matlab.ui.control.Label
        Switch                   matlab.ui.control.Switch
        Panel_4                  matlab.ui.container.Panel
        GridLayout4              matlab.ui.container.GridLayout
        Panel_7                  matlab.ui.container.Panel
        GridLayout12             matlab.ui.container.GridLayout
        MONOLabel_2              matlab.ui.control.Label
        ShitfButton              matlab.ui.control.Button
        PlaychannelButton_2      matlab.ui.control.Button
        AddfilterPanel_2         matlab.ui.container.Panel
        GridLayout6_2            matlab.ui.container.GridLayout
        ApplyButton_2            matlab.ui.control.Button
        IIRButton_2              matlab.ui.control.Button
        FIRButton_2              matlab.ui.control.Button
        Panel_5                  matlab.ui.container.Panel
        GridLayout5              matlab.ui.container.GridLayout
        Knob                     matlab.ui.control.Knob
        Panel_2                  matlab.ui.container.Panel
        GridLayout3              matlab.ui.container.GridLayout
        Panel_6                  matlab.ui.container.Panel
        GridLayout11             matlab.ui.container.GridLayout
        MONOLabel                matlab.ui.control.Label
        ShiftButton              matlab.ui.control.Button
        PlaychannelButton        matlab.ui.control.Button
        AddfilterPanel           matlab.ui.container.Panel
        GridLayout6              matlab.ui.container.GridLayout
        ApplyButton              matlab.ui.control.Button
        IIRButton                matlab.ui.control.Button
        FIRButton                matlab.ui.control.Button
        Panel                    matlab.ui.container.Panel
        GridLayout2              matlab.ui.container.GridLayout
        ConverttomonoButton      matlab.ui.control.Button
        ConverttostereoButton    matlab.ui.control.Button
        Playbacksignal2Button    matlab.ui.control.Button
        Playbacksignal1Button    matlab.ui.control.Button
        MultiplyButton           matlab.ui.control.Button
        AddButton                matlab.ui.control.Button
        TabGroup                 matlab.ui.container.TabGroup
        Channel1Tab              matlab.ui.container.Tab
        GridLayout8              matlab.ui.container.GridLayout
        UIAxes3                  matlab.ui.control.UIAxes
        Channel2Tab              matlab.ui.container.Tab
        GridLayout8_2            matlab.ui.container.GridLayout
        UIAxes3_2                matlab.ui.control.UIAxes
        Out1Tab                  matlab.ui.container.Tab
        GridLayout8_3            matlab.ui.container.GridLayout
        UIAxes3_3                matlab.ui.control.UIAxes
        Out2Tab                  matlab.ui.container.Tab
        GridLayout8_4            matlab.ui.container.GridLayout
        UIAxes3_4                matlab.ui.control.UIAxes
        Result1Tab               matlab.ui.container.Tab
        GridLayout9              matlab.ui.container.GridLayout
        UIAxes3_9                matlab.ui.control.UIAxes
        UIAxes                   matlab.ui.control.UIAxes
        UIAxes2                  matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        SyntheticSignalApp                      % Aplicacion señal sintetica
        OperationSig                            % Aplicacion operacion suma y multiplicacion
        MonoToStereo                            % Aplicacion mono a estereo
        StereoToMono                            % Aplicacion estereo a mono
        CreateSig                               % Aplicacion crear señal propia
        ViewFrec                                % Aplicacion para visualizar en el dominio de la frecuencia
        dataSignal1_1 = [];                     % Datos de la primera grafica canal 1
        dataSignal1_2 = [];                     % Datos de la primera grafica canal 2
        dataSignal1_3 = [];                     % Datos de la primera grafica out 1
        dataSignal1_4 = [];                     % Datos de la primera grafica out 2
        dataSignal2_1 = [];                     % Datos de la segunda grafica canal 1
        dataSignal2_2 = [];                     % Datos de la segunda grafica canal 2
        dataSignal2_3 = [];                     % Datos de la segunda grafica out 1
        dataSignal2_4 = [];                     % Datos de la segunda grafica out 2
        dataSignalResult1 = [];                 % Datos del resultado 1
        dataSignalResult2 = [];                 % Datos del resultado 2
        arraySamplingFrequencyGraph1 = [];      % Frecuencias de muestreo (canal 1, canal 2, out 1, out 2, result1)
        arraySamplingFrequencyGraph2 = [];      % Frecuencias de muestreo (canal 1, canal 2, out 1, out 2, result2)

        dataSignalHn1_1 = [];                   % Datos de la respuesta al impulso de la señal 1 de canal 1.
        dataSignalHn1_2 = [];                   % Datos de la respuesta al impulso de la señal 1 de canal 2.
        dataSignalHn2_1 = [];                   % Datos de la respuesta al impulso de la señal 2 de canal 1.
        dataSignalHn2_2 = [];                   % Datos de la respuesta al impulso de la señal 2 de canal 2.
        zero = [];                              % Posiciones iniciales de la respuesta al impulso.
        channelAux = [];                        % Guarda el canal de la señal a convolucionar.

        dataAuxYnTemp1 = [];                    % Datos temporales de la respuesta "y" obenida luego de aplicar la convolución o IIR señal 1.
        dataAuxYnTemp2 = [];                    % Datos temporales de la respuesta "y" obenida luego de aplicar la convolución o IIR señal 2.

        aux1ValueOperation = [];                % Varieable auxiliar que guarda el canal seleccionado 1.
        aux2ValueOperation = [];                % Varieable auxiliar que guarda el canal seleccionado 2.
        aux3ValueOperation = [];                % Varieable auxiliar que guarda el canal seleccionado.

        auxCloseConvolutionWindow1;             %Varaible para cerrar la ventana que se ha generado para la convolución o IIR.
        auxCloseConvolutionWindow2;             %Varaible para cerrar la ventana que se ha generado para la convolución o IIR.

        signalChannelButton1 = 1;               % Control ventana abierta señal 1
        signalChannelButton2 = 1;               % Control ventana abierta señal 2

        typeFilter1 = 0;                        % (Convolución por bloques = 0 / IIR = 1) para señal 1.
        typeFilter2 = 0;                        % (Convolución por bloques = 0 / IIR = 1) para señal 2.

        signalType1 = 'mono';                   % Indica si mi señal 1 es mono o estereo
        signalType2 = 'mono';                   % Indica si mi señal 2 es mono o estereo

    end
    
    methods (Access = public)

        % Funcion encargada de graficar la señal 1 (instancia, n, x(n), canal, frecuencia de muestreo)
        function graphSignal1(app, dataN, dataX, number, sampFrec)
            % Verficamos que la señal no tengan valores no validos:
            dataX = elminateInfNan(app, dataX);
            % Dependiendo de number se guarda en el dataSignal respectivo y lo grafica (tambien su fs)
            switch number
                % Canal 1
                case 0
                    app.arraySamplingFrequencyGraph1 (1) = sampFrec;
                    app.dataSignal1_1 = [dataN; dataX];
                    stem(app.UIAxes3, app.dataSignal1_1(1,:), app.dataSignal1_1(2,:),".");
                % Canal 2
                case 1
                    app.arraySamplingFrequencyGraph1 (2) = sampFrec;
                    app.dataSignal1_2 = [dataN; dataX];
                    stem(app.UIAxes3_2, app.dataSignal1_2(1,:), app.dataSignal1_2(2,:),".");
                % Out 1
                case 3
                    app.arraySamplingFrequencyGraph1 (3) = sampFrec;
                    app.dataSignal1_3 = [dataN; dataX];
                    stem(app.UIAxes3_3, app.dataSignal1_3(1,:), app.dataSignal1_3(2,:),".");
                % Out 2
                case 4
                    app.arraySamplingFrequencyGraph1 (4) = sampFrec;
                    app.dataSignal1_4 = [dataN; dataX];
                    stem(app.UIAxes3_4, app.dataSignal1_4(1,:), app.dataSignal1_4(2,:),".");      
                % Result 1
                case 5
                    app.arraySamplingFrequencyGraph1 (5) = sampFrec;
                    app.dataSignalResult1 = [dataN; dataX];
                    stem(app.UIAxes3_9, app.dataSignalResult1(1,:), app.dataSignalResult1(2,:),"."); 
            end
        end
        % Funcion encargada de graficar la señal 2 (instancia, n, x(n), canal, frecuencia de muestreo)
        function graphSignal2(app, dataN, dataX, number, sampFrec)
            % Verficamos que la señal no tengan valores no validos:
            dataX = elminateInfNan(app, dataX);
            % Dependiendo de number se guarda en el dataSignal respectivo y lo grafica (tambien su fs)
            switch number
                % Canal 1
                case 0
                    app.arraySamplingFrequencyGraph2 (1) = sampFrec;
                    app.dataSignal2_1 = [dataN; dataX];
                    stem(app.UIAxes3_5, app.dataSignal2_1(1,:), app.dataSignal2_1(2,:),".");
                % Canal 2
                case 1
                    app.arraySamplingFrequencyGraph2 (2) = sampFrec;
                    app.dataSignal2_2 = [dataN; dataX];
                    stem(app.UIAxes3_6, app.dataSignal2_2(1,:), app.dataSignal2_2(2,:),".");
                % Out 1
                case 3
                    app.arraySamplingFrequencyGraph2 (3) = sampFrec;
                    app.dataSignal2_3 = [dataN; dataX];
                    stem(app.UIAxes3_7, app.dataSignal2_3(1,:), app.dataSignal2_3(2,:),".");
                % Out 2
                case 4
                    app.arraySamplingFrequencyGraph2 (4) = sampFrec;
                    app.dataSignal2_4 = [dataN; dataX];
                    stem(app.UIAxes3_8, app.dataSignal2_4(1,:), app.dataSignal2_4(2,:),".");
                % Result 2
                case 5
                    app.arraySamplingFrequencyGraph2 (5) = sampFrec;
                    app.dataSignalResult2 = [dataN; dataX];
                    stem(app.UIAxes3_10, app.dataSignalResult2(1,:), app.dataSignalResult2(2,:),"."); 
            end
        end
        % Funcion que llama a las funciones de suma y multiplicacion
        % input1 se refiere a que canal se seleciono de la señal para operar con la señal 2 (y viceversa)
        function operationSignal(app, operation, save, input1, input2)
            % Selecciona la señal respectiva 1
            switch input1
                case 'Channel 1'
                    app.aux1ValueOperation = app.dataSignal1_1;
                    app.aux3ValueOperation(1) = app.arraySamplingFrequencyGraph1(1);
                case 'Channel 2'
                    app.aux1ValueOperation = app.dataSignal1_2;
                    app.aux3ValueOperation(1) = app.arraySamplingFrequencyGraph1(2);
                case 'Out 1'
                    app.aux1ValueOperation = app.dataSignal1_3;
                    app.aux3ValueOperation(1) = app.arraySamplingFrequencyGraph1(3);
                case 'Out 2'
                    app.aux1ValueOperation = app.dataSignal1_4;
                    app.aux3ValueOperation(1) = app.arraySamplingFrequencyGraph1(4);
            end
            % Selecciona la señal respectiva 2
            switch input2
                case 'Channel 1'
                    app.aux2ValueOperation = app.dataSignal2_1;
                    app.aux3ValueOperation(2) = app.arraySamplingFrequencyGraph2(1);
                case 'Channel 2'
                    app.aux2ValueOperation = app.dataSignal2_2;
                    app.aux3ValueOperation(2) = app.arraySamplingFrequencyGraph2(2);
                case 'Out 1'
                    app.aux2ValueOperation = app.dataSignal2_3;
                    app.aux3ValueOperation(2) = app.arraySamplingFrequencyGraph2(3);
                case 'Out 2'
                    app.aux2ValueOperation = app.dataSignal2_4;
                    app.aux3ValueOperation(2) = app.arraySamplingFrequencyGraph2(4);
            end
            % Selecciona la grafica en la que guardar
            switch save
                case 'Result 1'
                    % Condicional que selecciona que operacion se realiza
                    % Suma
                    if operation == 0
                        app.dataSignalResult1 = addFuntion(app.aux1ValueOperation, app.aux2ValueOperation);
                        graphSignal1(app, app.dataSignalResult1(1, :), app.dataSignalResult1(2, :));
                    % Multiplicacion
                    elseif operation == 1
                        app.dataSignalResult1 = multiplyFuntion(app.aux1ValueOperation, app.aux2ValueOperation);
                        graphSignal1(app, app.dataSignalResult1(1, :), app.dataSignalResult1(2, :));
                    end
                case 'Result 2'
                    % Suma
                    if operation == 0
                        app.dataSignalResult2 = addFuntion(app.aux1ValueOperation, app.aux2ValueOperation);
                        graphSignal1(app, app.dataSignalResult2(1, :), app.dataSignalResult2(2, :));
                    % Multiplicacion
                    elseif operation == 1
                        app.dataSignalResult2 = multiplyFuntion(app.aux1ValueOperation, app.aux2ValueOperation);
                        graphSignal1(app, app.dataSignalResult1(2, :), app.dataSignalResult2(2, :));
                    end
            end
        end
        % Funcion que cambia los colores de las pestaña de las graficas
        % dependiendo de si la señal 1 u 2 es mono o estereo
        function colorMonoStereo(app, graph, type)
            switch graph
                %% Señal 1
                case 0
                    switch type
                        % Mono
                        case 'mono'
                            % Cambiar color señal 1
                            app.MONOLabel.Text = 'MONO';
                            app.GridLayout11.BackgroundColor = [0.76,0.87,0.94];
                            app.GridLayout8.BackgroundColor = [0.76,0.87,0.94];
                            app.GridLayout8_2.BackgroundColor = [0.94,0.76,0.76];
                            app.GridLayout8_3.BackgroundColor = [0.76,0.87,0.94];
                            app.GridLayout8_4.BackgroundColor = [0.94,0.76,0.76];
                        % Stereo
                        case 'stereo'    
                            % Cambiar color señal 1
                            app.MONOLabel.Text = 'STEREO';
                            app.GridLayout11.BackgroundColor = [0.94,0.76,0.76];
                            app.GridLayout8.BackgroundColor = [0.76,0.87,0.94];
                            app.GridLayout8_2.BackgroundColor = [0.76,0.87,0.94];
                            app.GridLayout8_3.BackgroundColor = [0.94,0.76,0.76];
                            app.GridLayout8_4.BackgroundColor = [0.94,0.76,0.76];
                    end 
                %% Señal 2
                case 1
                    switch type
                        % Mono
                        case 'mono'
                            % Cambiar color señal 1
                            app.MONOLabel_2.Text = 'MONO';
                            app.GridLayout12.BackgroundColor = [0.76,0.87,0.94];
                            app.GridLayout8_5.BackgroundColor = [0.76,0.87,0.94];
                            app.GridLayout8_6.BackgroundColor = [0.94,0.76,0.76];
                            app.GridLayout8_7.BackgroundColor = [0.76,0.87,0.94];
                            app.GridLayout8_8.BackgroundColor = [0.94,0.76,0.76];
                        % Stereo
                        case 'stereo'    
                            % Cambiar color señal 1
                            app.MONOLabel_2.Text = 'STEREO';
                            app.GridLayout12.BackgroundColor = [0.94,0.76,0.76];
                            app.GridLayout8_5.BackgroundColor = [0.76,0.87,0.94];
                            app.GridLayout8_6.BackgroundColor = [0.76,0.87,0.94];
                            app.GridLayout8_7.BackgroundColor = [0.94,0.76,0.76];
                            app.GridLayout8_8.BackgroundColor = [0.94,0.76,0.76];
                   end  
            end
        end
        % Funcion que convierte a la señal 1 o 2 en estereo
        function monoStereo(app, signal, channel1, channel2)
            % Canal 1
            if channel1 == "Channel 1 S1"
                aux = app.dataSignal1_1;
                auxFs = app.arraySamplingFrequencyGraph1(1);
            elseif channel1 == "Channel 2 S1"
                aux = app.dataSignal1_2;
                auxFs = app.arraySamplingFrequencyGraph1(2);
            elseif channel1 == "Out 1 S1"
                aux = app.dataSignal1_3;
                auxFs = app.arraySamplingFrequencyGraph1(3);
            elseif channel1 == "Out 2 S1"
                aux = app.dataSignal1_4;
                auxFs = app.arraySamplingFrequencyGraph1(4);
            elseif channel1 == "Channel 1 S2"
                aux = app.dataSignal2_1;
                auxFs = app.arraySamplingFrequencyGraph2(1);
            elseif channel1 == "Channel 2 S2"
                aux = app.dataSignal2_2;
                auxFs = app.arraySamplingFrequencyGraph2(2);
            elseif channel1 == "Out 1 S2"
                aux = app.dataSignal2_3;
                auxFs = app.arraySamplingFrequencyGraph2(3);
            elseif channel1 == "Out 2 S2"
                aux = app.dataSignal2_4;
                auxFs = app.arraySamplingFrequencyGraph2(4);
            elseif channel1 == "Result 1"
                aux = app.dataSignalResult1;
                auxFs = app.arraySamplingFrequencyGraph1(5);
            elseif channel1 == "Result 2"
                aux = app.dataSignalResult2;
                auxFs = app.arraySamplingFrequencyGraph2(5);
            elseif channel1 == "Empty"
                aux = [];
                auxFs = 1;
            end
            % Canal 2
            if channel2 == "Channel 1 S1"
                aux2 = app.dataSignal1_1;
                auxFs2 = app.arraySamplingFrequencyGraph1(1);
            elseif channel2 == "Channel 2 S1"
                aux2 = app.dataSignal1_2;
                auxFs2 = app.arraySamplingFrequencyGraph1(2);
            elseif channel2 == "Out 1 S1"
                aux2 = app.dataSignal1_3;
                auxFs2 = app.arraySamplingFrequencyGraph1(3);
            elseif channel2 == "Out 2 S1"
                aux2 = app.dataSignal1_4;
                auxFs2 = app.arraySamplingFrequencyGraph1(4);
            elseif channel2 == "Channel 1 S2"
                aux2 = app.dataSignal2_1;
                auxFs2 = app.arraySamplingFrequencyGraph2(1);
            elseif channel2 == "Channel 2 S2"
                aux2 = app.dataSignal2_2;
                auxFs2 = app.arraySamplingFrequencyGraph2(2);
            elseif channel2 == "Out 1 S2"
                aux2 = app.dataSignal2_3;
                auxFs2 = app.arraySamplingFrequencyGraph2(3);
            elseif channel2 == "Out 2 S2"
                aux2 = app.dataSignal2_4;
                auxFs2 = app.arraySamplingFrequencyGraph2(4);
            elseif channel2 == "Result 1"
                aux2 = app.dataSignalResult1;
                auxFs2 = app.arraySamplingFrequencyGraph1(5);
            elseif channel2 == "Result 2"
                aux2 = app.dataSignalResult2;
                auxFs2 = app.arraySamplingFrequencyGraph2(5);
            elseif channel2 == "Empty"
                aux2 = [];
                auxFs2 = 1;
            end
            [aux, auxFs, aux2, auxFs2] = makePaddingFrecuency(app, aux, auxFs, aux2, auxFs2);
            aux3 = [aux; aux2];
            switch signal
                % Señal 1
                case 0
                    graphSignal1(app, aux3(1,:), aux3(2,:), 0, auxFs);
                    graphSignal1(app, aux3(size(aux,1) + 1,:), aux3(size(aux,1) + 2,:), 1, auxFs2);
                    app.signalType1 = 'stereo';
                    colorMonoStereo(app, 0, 'stereo')
                % Señal 2
                case 1
                    graphSignal2(app, aux3(1,:), aux3(2,:), 0, auxFs);
                    graphSignal2(app, aux3(size(aux,1) + 1,:), aux3(size(aux,1) + 2,:), 1, auxFs2);
                    app.signalType2 = 'stereo';
                    colorMonoStereo(app, 1, 'stereo')
            end
        end
        % Funcion que convierte a la señal 1 o 2 en mono
        function stereoMono(app, signal, saveTo, options)
            % Canal que se guardara
            if options == "Keep Channel 1 S1"
                aux2 = app.dataSignal1_1;
                auxFs2 = app.arraySamplingFrequencyGraph1(1);
            elseif options == "Keep Channel 2 S1"
                aux2 = app.dataSignal1_2;
                auxFs2 = app.arraySamplingFrequencyGraph1(2);
            elseif options == "Keep Out 1 S1"
                aux2 = app.dataSignal1_3;
                auxFs2 = app.arraySamplingFrequencyGraph1(3);
            elseif options == "Keep Out 2 S1"
                aux2 = app.dataSignal1_4;
                auxFs2 = app.arraySamplingFrequencyGraph1(4);
            elseif options == "Keep Channel 1 S2"
                aux2 = app.dataSignal2_1;
                auxFs2 = app.arraySamplingFrequencyGraph2(1);
            elseif options == "Keep Channel 2 S2"
                aux2 = app.dataSignal2_2;
                auxFs2 = app.arraySamplingFrequencyGraph2(2);
            elseif options == "Keep Out 1 S2"
                aux2 = app.dataSignal2_3;
                auxFs2 = app.arraySamplingFrequencyGraph2(3);
            elseif options == "Keep Out 2 S2"
                aux2 = app.dataSignal2_4;
                auxFs2 = app.arraySamplingFrequencyGraph2(4);
            elseif options == "Keep Result 1"
                aux2 = app.dataSignalResult1;
                auxFs2 = app.arraySamplingFrequencyGraph1(5);
            elseif options == "Keep Result 2"
                aux2 = app.dataSignalResult2;
                auxFs2 = app.arraySamplingFrequencyGraph2(5);
            elseif options == "Average Ch1 & Ch2"
                if signal == 0
                    signal1 = app.dataSignal1_1;
                    signal2 = app.dataSignal1_2;
                    fs1 = app.arraySamplingFrequencyGraph1(1);
                    fs2 = app.arraySamplingFrequencyGraph1(2);
                    [signal1, fs1, signal2, fs2] = makePaddingFrecuency(app, signal1, fs1, signal2, fs2);
                    aux2 = addFuntion(signal1, signal2);
                    auxFs2 = fs1;
                elseif signal == 1
                    signal1 = app.dataSignal2_1;
                    signal2 = app.dataSignal2_2;
                    fs1 = app.arraySamplingFrequencyGraph2(1);
                    fs2 = app.arraySamplingFrequencyGraph2(2);
                    [signal1, fs1, signal2, fs2] = makePaddingFrecuency(app, signal1, fs1, signal2, fs2);
                    aux2 = addFuntion(signal1, signal2);
                    auxFs2 = fs1;
                end 
            end
            switch signal
                % Señal 1
                case 0
                    % Canal a guardar
                    if saveTo == "Channel 1"
                        graphSignal1(app, aux2(1,:), aux2(2,:), 0, auxFs2);
                    elseif saveTo == "Channel 2"
                        graphSignal1(app, aux2(1,:), aux2(2,:), 1, auxFs2);
                    end
                    app.signalType1 = 'mono';
                    colorMonoStereo(app, 0, 'mono')
                % Señal 2
                case 1
                    % Canal a guardar
                    if saveTo == "Channel 1"
                        graphSignal2(app, aux2(1,:), aux2(2,:), 0, auxFs2);
                    elseif saveTo == "Channel 2"
                        graphSignal2(app, aux2(1,:), aux2(2,:), 1, auxFs2);
                    end
                    app.signalType2 = 'mono';
                    colorMonoStereo(app, 1, 'mono')
            end
        end
        
        % Función para guardar la respuesta al impulso seleccionada.
        function savehnConvolucion(app, hn, kn, chanel, zero, y, signal, proprietary)
            if(signal == 1)
                switch chanel
                    case 1
                        % Para cuando este en channel 1.
                        app.dataSignalHn1_1 = [hn; kn];
                        app.zero(1) = zero;
                    case 2
                        % Para cuando este en channel 2.
                        app.dataSignalHn1_2 = [hn; kn];
                        app.zero(2) = zero;
                    case 3
                        % Para cuando este en out 1.
                        app.dataSignalHn1_1 = [hn; kn];
                        app.zero(1) = zero;
                    case 4
                        % Para cuando este en out 2.
                        app.dataSignalHn1_2 = [hn; kn];
                        app.zero(2) = zero;
                    case 5
                        % Para cuando este en result 1.
                        app.dataSignalHn1_1 = [hn; kn];
                        app.zero(1) = zero;
                end
                app.dataAuxYnTemp1 = y;
                app.channelAux(1) = chanel;
                app.auxCloseConvolutionWindow1 = proprietary;
                app.typeFilter1 = 0;
            else
                switch chanel
                    case 1
                        % Para cuando este en channel 1.
                        app.dataSignalHn2_1 = [hn; kn];
                        app.zero(3) = zero;
                    case 2
                        % Para cuando este en channel 2.
                        app.dataSignalHn2_2 = [hn; kn];
                        app.zero(4) = zero;
                    case 3
                        % Para cuando este en out 1.
                        app.dataSignalHn2_1 = [hn; kn];
                        app.zero(3) = zero;
                    case 4
                        % Para cuando este en out 2.
                        app.dataSignalHn2_2 = [hn; kn];
                        app.zero(4) = zero;
                    case 5
                        % Para cuando este en result 1.
                        app.dataSignalHn2_1 = [hn; kn];
                        app.zero(3) = zero;
                end
                app.dataAuxYnTemp2 = y;
                app.channelAux(2) = chanel;
                app.auxCloseConvolutionWindow2 = proprietary;
                app.typeFilter2 = 0;
            end
        end

        % Función para graficar la respuesta al impulso:
        function graphHn(app, n, hn, title, signal)
            if(signal == 1)
                stem(app.UIAxes, n, hn);
                app.UIAxes.YGrid = 'on';
                app.UIAxes.XGrid = 'on';
                app.UIAxes.Title.String = string(title);
            else
                stem(app.UIAxes2, n, hn);
                app.UIAxes2.YGrid = 'on';
                app.UIAxes2.XGrid = 'on';
                app.UIAxes2.Title.String = string(title);
            end
        end
        
        % Función para eliminar valores no validos:
        function newSignal = elminateInfNan(app, signal)
            signal(isinf(signal) | isnan(signal)) = 0;
            newSignal = signal;
        end
        
        % Función para realizar padding y cambio de frecuencia:
        function [signal1, fs1, signal2, fs2] = makePaddingFrecuency(app, signal1, fs1, signal2, fs2)
            if fs1 ~= fs2
                [signal1, fs1, signal2, fs2] = changeFrecuency(app, signal1, fs1, signal2, fs2);
            end
            if~((signal1(1,1) == signal2(1,1)) && (signal1(1, end) == signal2(1, end)))
                [signal1, signal2]= paddingDigital(app, signal1, signal2);
            end
        end

        %% Función para realizar la suma
        function results = addfunction(app, signal1, signal2)
            results = signal1(2, :) + signal2(2, :);
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Configura la posición de la figura para centrarla
           app.SignalProcessorUIFigure.WindowState = 'maximized';
        end

        % Menu selected function: SineMenu
        function SineMenuSelected(app, event)
            % Seno
            app.SyntheticSignalApp = CreateSyntheticSignalApp(app, 0);
        end

        % Menu selected function: CosineMenu
        function CosineMenuSelected(app, event)
            % Coseno
            app.SyntheticSignalApp = CreateSyntheticSignalApp(app, 1);
        end

        % Menu selected function: ChirpMenu
        function ChirpMenuSelected(app, event)
            % Chirp
            app.SyntheticSignalApp = CreateSyntheticSignalApp(app, 2);
        end

        % Menu selected function: StepMenu
        function StepMenuSelected(app, event)
            % Escalon
            app.SyntheticSignalApp = CreateSyntheticSignalApp(app, 3);
        end

        % Menu selected function: RampMenu
        function RampMenuSelected(app, event)
            % Rampa
            app.SyntheticSignalApp = CreateSyntheticSignalApp(app, 4);
        end

        % Menu selected function: SawtoothMenu
        function SawtoothMenuSelected(app, event)
            % Diente de sierra
            app.SyntheticSignalApp = CreateSyntheticSignalApp(app, 5);
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            % Sumar señales
            app.OperationSig = OperationSignals(app, 0, app.signalChannelButton1, app.signalChannelButton2);
        end

        % Button pushed function: MultiplyButton
        function MultiplyButtonPushed(app, event)
            % Multiplicar señales
            app.OperationSig = OperationSignals(app, 1, app.signalChannelButton1, app.signalChannelButton2);
        end

        % Button down function: Channel1Tab
        function Channel1TabButtonDown(app, event)
            app.signalChannelButton1 = 1;
        end

        % Button down function: Channel2Tab
        function Channel2TabButtonDown(app, event)
            app.signalChannelButton1 = 2;
        end

        % Button down function: Out1Tab
        function Out1TabButtonDown(app, event)
            app.signalChannelButton1 = 3;
        end

        % Button down function: Out2Tab
        function Out2TabButtonDown(app, event)
            app.signalChannelButton1 = 4;
        end

        % Button down function: Result1Tab
        function Result1TabButtonDown(app, event)
            app.signalChannelButton1 = 5;
        end

        % Button down function: Channel1Tab_2
        function Channel1Tab_2ButtonDown(app, event)
            app.signalChannelButton2 = 1;
        end

        % Button down function: Channel2Tab_2
        function Channel2Tab_2ButtonDown(app, event)
            app.signalChannelButton2 = 2;
        end

        % Button down function: Out1Tab_2
        function Out1Tab_2ButtonDown(app, event)
            app.signalChannelButton2 = 3;
        end

        % Button down function: Out2Tab_2
        function Out2Tab_2ButtonDown(app, event)
            app.signalChannelButton2 = 4;
        end

        % Button down function: Result2Tab
        function Result2TabButtonDown(app, event)
            app.signalChannelButton2 = 5;
        end

        % Button pushed function: PlaychannelButton
        function PlaychannelButtonPushed(app, event)
            % Reproducir audio de la señal 1 dependiendo del canal
            % Solicitar fs e invertir
            dlg_title = 'Options';
            prompt = {'Sampling Frequency (Hz)', 'Invert (0,1)'};
            num_lines = 1;
            dims = [1 50];
            defaultans = {'44100', '0'};
            answer = inputdlg(prompt,dlg_title,dims,defaultans);
            if ~isempty(answer)
                Fs = str2num(answer{1});
                invertOption = str2num(answer{2});
                % Si la eleccion no es valida
                if invertOption ~= 0 && invertOption ~=1
                    errordlg('The value of "Invert" must be 0 or 1. By default, 0 has been selected.', 'Error', 'modal');
                    invertOption = 0;
                else
                end
               % No invertir
                if invertOption == 0
                    switch app.signalChannelButton1
                        % Canal 1
                        case 1
                            sound(app.dataSignal1_1(2,:), Fs);
                        % Canal 2    
                        case 2
                            sound(app.dataSignal1_2(2,:), Fs);
                        % Out 1
                        case 3
                            sound(app.dataSignal1_3(2,:), Fs);
                        % Out 2
                        case 4
                            sound(app.dataSignal1_4(2,:), Fs);
                        % Result 1
                        case 5
                            sound(app.dataSignalResult1(2,:), Fs);
                    end
                % Invertir 
                elseif invertOption == 1
                    switch app.signalChannelButton1
                        % Canal 1
                        case 1
                            sound(fliplr(app.dataSignal1_1(2,:)), Fs);
                        % Canal 2
                        case 2
                            sound(fliplr(app.dataSignal1_2(2,:)), Fs);
                        % Out 1
                        case 3
                            sound(fliplr(app.dataSignal1_3(2,:)), Fs);
                        % Out 2
                        case 4
                            sound(fliplr(app.dataSignal1_4(2,:)), Fs);
                        % Result 2
                        case 5
                            sound(fliplr(app.dataSignalResult1(2,:)), Fs);
                    end
                end 
            else
            end
        end

        % Button pushed function: PlaychannelButton_2
        function PlaychannelButton_2Pushed(app, event)
            % Reproducir audio de la señal 2 dependiendo del canal
            % Solicitar fs e invertir
            dlg_title = 'Options';
            prompt = {'Sampling Frequency (Hz)', 'Invert (0,1)'};
            num_lines = 1;
            dims = [1 50];
            defaultans = {'44100', '0'};
            answer = inputdlg(prompt,dlg_title,dims,defaultans);
            if ~isempty(answer)
                Fs = str2num(answer{1});
                invertOption = str2num(answer{2});
                % Si la eleccion no es valida
                if invertOption ~= 0 && invertOption ~=1
                    errordlg('The value of "Invert" must be 0 or 1. By default, 0 has been selected.', 'Error', 'modal');
                    invertOption = 0;
                else
                end
                % No invertir
                if invertOption == 0
                    switch app.signalChannelButton2
                        % Canal 1
                        case 1
                            sound(app.dataSignal2_1(2,:), Fs);
                        % Canal 2
                        case 2
                            sound(app.dataSignal2_2(2,:), Fs);
                        % Out 1
                        case 3
                            sound(app.dataSignal2_3(2,:), Fs);
                        % Out 2
                        case 4
                            sound(app.dataSignal2_4(2,:), Fs);
                        % Result 1
                        case 5
                            sound(app.dataSignalResult2(2,:), Fs);
                    end
                % Invertir
                elseif invertOption == 1
                    switch app.signalChannelButton1
                        % Canal 1
                        case 1
                            sound(fliplr(app.dataSignal2_1(2,:)), Fs);
                        % Canal 2
                        case 2
                            sound(fliplr(app.dataSignal2_2(2,:)), Fs);
                        % Out 1
                        case 3
                            sound(fliplr(app.dataSignal2_3(2,:)), Fs);
                        % Out 2
                        case 4
                            sound(fliplr(app.dataSignal2_4(2,:)), Fs);
                        % Result 2
                        case 5
                            sound(fliplr(app.dataSignalResult2(2,:)), Fs);
                    end
                end 
            else
            end
        end

        % Menu selected function: AudiofileMenu
        function AudiofileMenuSelected(app, event)
            % Solicitar seleccionar audio
            [filename, pathname] = uigetfile('*.*', 'Select an audio file');
            [x,Fs] = audioread(fullfile(pathname,filename));
            n = 0:length(x)-1;
            % Solicitar seleccionar una señal
            options = {'Signal 1', 'Signal 2'};
            selection = listdlg('ListString', options, 'SelectionMode', 'single', 'PromptString', 'Select a signal to save:');          
            if ~isempty(selection)
                switch options{selection}
                    % Guarda en el canal 1 de la señal 1
                    case 'Signal 1'
                        x = x.';
                        % Si es estereo
                        if size(x, 1) == 2
                            app.signalType1 = 'stereo';
                            colorMonoStereo(app, 0, app.signalType1);
                            graphSignal1(app, n, x(1,:), 0, Fs);
                            graphSignal1(app, n, x(2,:), 1, Fs);
                        else
                            % Sino considerarla mono
                            app.signalType1 = 'mono';
                            colorMonoStereo(app, 0, app.signalType1);
                            % Solicitar seleccionar un canal
                            options2 = {'Channel 1','Channel 2'};
                            selection2 = listdlg('ListString', options2, 'SelectionMode', 'single', 'PromptString', 'Select a channel to save:');
                            if ~isempty(selection2)
                                switch options2{selection2}
                                    case 'Channel 1'
                                        graphSignal1(app, n, x(1,:), 0, Fs);
                                    case 'Channel 2'
                                        graphSignal1(app, n, x(1,:), 1, Fs);
                                end
                            end
                        end
                    % Guarda en el canal 1 de la señal 2
                    case 'Signal 2'
                        x = x.';
                        % Si es estereo
                        if size(x, 1) == 2
                            app.signalType2 = 'stereo';
                            colorMonoStereo(app, 1, app.signalType2);
                            graphSignal2(app, n, x(1,:), 0, Fs);
                            graphSignal2(app, n, x(2,:), 1, Fs);
                        else
                            % Sino considerarla mono
                            app.signalType2 = 'mono';
                            colorMonoStereo(app, 1, app.signalType2);
                            % Solicitar seleccionar un canal
                            options2 = {'Channel 1','Channel 2'};
                            selection2 = listdlg('ListString', options2, 'SelectionMode', 'single', 'PromptString', 'Select a channel to save:');
                            if ~isempty(selection2)
                                switch options2{selection2}
                                    case 'Channel 1'
                                        graphSignal2(app, n, x(1,:), 0, Fs);
                                    case 'Channel 2'
                                        graphSignal2(app, n, x(1,:), 1, Fs);
                                end
                            end
                        end
                end
            else
            end
        end

        % Menu selected function: RecordaudioMenu
        function RecordaudioMenuSelected(app, event)
            % Solicitar parametros de grabacion
            dlg_title = 'Record audio';
            prompt = { 'Recording time (s)','Bits/samples', 'Sampling Frequency (Hz)','Number of channels (1,2)'};
            num_lines = 1;
            defaultans = {'7', '16','44100', '1'};
            answer = inputdlg(prompt,dlg_title,num_lines,defaultans);
            if ~isempty(answer)
                tRec=str2num(answer{1});    % Tiempo de grabación en s
                nBit=str2num(answer{2});   % Número de bits por muestra
                Fs=str2num(answer{3});    % Frecuencia de muestreo  
                nChan=str2num(answer{4});   % Número de canales
                % Si la eleccion de nChan no es valida
                if nChan ~= 1 && nChan ~=2
                    errordlg('The value of "Number of channels" must be 1 or 2. By default, 0 has been selected.', 'Error', 'modal');
                    nChan = 1;
                else
                end
                % Crear un objeto audiorecorder
                aud1 = audiorecorder(Fs,nBit,nChan);
                f = waitbar(0,'Recording');
                recordblocking(aud1, tRec); % Grabar  audio
                waitbar(1,f,'Recording');
                close(f);
                x = getaudiodata(aud1); % Obtener los datos de la señal x
                n = 0:length(x)-1;
                % Guardar
                options = {'Signal 1', 'Signal 2'};
                selection = listdlg('ListString', options, 'SelectionMode', 'single', 'PromptString', 'Select a signal to save:');
                if ~isempty(selection)
                    switch options{selection}
                        % Guarda en el canal 1 de la señal 1
                        case 'Signal 1'
                            x = x.';
                            % Si es estereo
                            if size(x, 1) == 2
                                app.signalType1 = 'stereo';
                                colorMonoStereo(app, 0, app.signalType1);
                                graphSignal1(app, n, x(1,:), 0, Fs);
                                graphSignal1(app, n, x(2,:), 1, Fs);
                            else
                                % Sino considerarla mono
                                app.signalType1 = 'mono';
                                colorMonoStereo(app, 0, app.signalType1);
                                % Solicitar seleccionar un canal
                                options2 = {'Channel 1','Channel 2'};
                                selection2 = listdlg('ListString', options2, 'SelectionMode', 'single', 'PromptString', 'Select a channel to save:');
                                if ~isempty(selection2)
                                    switch options2{selection2}
                                        case 'Channel 1'
                                            graphSignal1(app, n, x(1,:), 0, Fs);
                                        case 'Channel 2'
                                            graphSignal1(app, n, x(1,:), 1, Fs);
                                    end
                                end
                            end
                        % Guarda en el canal 1 de la señal 2
                        case 'Signal 2'
                            x = x.';
                            graphSignal2(app, n, x(1,:), 0, Fs);
                            % Si es estereo
                            if size(x, 1) == 2
                                app.signalType2 = 'stereo';
                                colorMonoStereo(app, 1, app.signalType2);
                                graphSignal2(app, n, x(2,:), 1, Fs);
                            else
                                % Sino considerarla mono
                                app.signalType2 = 'mono';
                                colorMonoStereo(app, 1, app.signalType2);
                                % Solicitar seleccionar un canal
                                options2 = {'Channel 1','Channel 2'};
                                selection2 = listdlg('ListString', options2, 'SelectionMode', 'single', 'PromptString', 'Select a channel to save:');
                                if ~isempty(selection2)
                                    switch options2{selection2}
                                        case 'Channel 1'
                                            graphSignal2(app, n, x(1,:), 0, Fs);
                                        case 'Channel 2'
                                            graphSignal2(app, n, x(1,:), 1, Fs);
                                    end
                                end
                            end
                    end
                else
                end
            else
            end
        end

        % Button pushed function: Playbacksignal1Button
        function Playbacksignal1ButtonPushed(app, event)
            % Verificar si la señal es estereo
            if app.signalType1 == "stereo"
                % Reproducir audio de la señal 1
                dlg_title = 'Options';
                prompt = {'Sampling Frequency (Hz)', 'Invert (0,1)'};
                num_lines = 1;
                dims = [1 50];
                defaultans = {'44100', '0'};  
                answer = inputdlg(prompt,dlg_title,dims,defaultans);
                if ~isempty(answer)
                    Fs = str2num(answer{1});
                    invertOption = str2num(answer{2});
                    % Si la eleccion no es valida
                    if invertOption ~= 0 && invertOption ~=1
                        errordlg('The value of "Invert" must be 0 or 1. By default, 0 has been selected.', 'Error', 'modal');
                        invertOption = 0;
                    else
                    end
                    % No invertir
                    if invertOption == 0
                        switch app.signalChannelButton1
                            % Si esta ubicado en el canal 1 0 2 de la señal 1
                            case {1,2}
                                % REQUIERE PADDING
                                signal1 = app.dataSignal1_1;
                                signal2 = app.dataSignal1_2;
                                fs1 = app.arraySamplingFrequencyGraph1(1);
                                fs2 = app.arraySamplingFrequencyGraph1(2);
                                [signal1, fs1, signal2, fs2] = makePaddingFrecuency(app, signal1, fs1, signal2, fs2);
                                y = [signal1(2,:);signal2(2,:)];
                                y = y.';
                                % Comprobar que las dos señales tiene la misma Fs
                                sound(y, Fs);
                            % Si esta ubicado en el out 1 0 2 de la señal 1
                            case {3,4}
                                % REQUIERE PADDING
                                signal1 = app.dataSignal1_3;
                                signal2 = app.dataSignal1_4;
                                fs1 = app.arraySamplingFrequencyGraph1(3);
                                fs2 = app.arraySamplingFrequencyGraph1(4);
                                [signal1, fs1, signal2, fs2] = makePaddingFrecuency(app, signal1, fs1, signal2, fs2);
                                y = [signal1(2,:);signal2(2,:)];
                                y = y.';
                                % Comprobar que las dos señales tiene la misma Fs
                                sound(y, Fs);
                        end
                    % Invertir
                    elseif invertOption == 1
                        switch app.signalChannelButton1
                            % Si esta ubicado en el canal 1 0 2 de la señal 1
                            case {1,2}
                                % REQUIERE PADDING
                                signal1 = app.dataSignal1_1;
                                signal2 = app.dataSignal1_2;
                                fs1 = app.arraySamplingFrequencyGraph1(1);
                                fs2 = app.arraySamplingFrequencyGraph1(2);
                                [signal1, fs1, signal2, fs2] = makePaddingFrecuency(app, signal1, fs1, signal2, fs2);
                                y = [signal1(2,:);signal2(2,:)];
                                y = y.';
                                % Comprobar que las dos señales tiene la misma Fs
                                sound(fliplr(y), Fs);
                            % Si esta ubicado en el out 1 0 2 de la señal 1
                            case {3,4}
                                % REQUIERE PADDING
                                signal1 = app.dataSignal1_3;
                                signal2 = app.dataSignal1_4;
                                fs1 = app.arraySamplingFrequencyGraph1(3);
                                fs2 = app.arraySamplingFrequencyGraph1(4);
                                [signal1, fs1, signal2, fs2] = makePaddingFrecuency(app, signal1, fs1, signal2, fs2);
                                y = [signal1(2,:);signal2(2,:)];
                                y = y.';
                                % Comprobar que las dos señales tiene la misma Fs
                                sound(fliplr(y), Fs);
                        end
                    end
                else
                end
            % Sino es estereo
            else
                errordlg('The signal is not stereo, please try again when it is.', 'Error', 'modal');
            end
        end

        % Button pushed function: Playbacksignal2Button
        function Playbacksignal2ButtonPushed(app, event)
            % Verificar si la señal es estereo
            if app.signalType2 == "stereo"
                % Reproducir audio de la señal 2
                dlg_title = 'Options';
                prompt = {'Sampling Frequency (Hz)', 'Invert (0,1)'};
                num_lines = 1;
                dims = [1 50];
                defaultans = {'44100', '0'};  
                answer = inputdlg(prompt,dlg_title,dims,defaultans);
                if ~isempty(answer)
                    Fs = str2num(answer{1});
                    invertOption = str2num(answer{2});
                    % Si la eleccion no es valida
                    if invertOption ~= 0 && invertOption ~=1
                        errordlg('The value of "Invert" must be 0 or 1. By default, 0 has been selected.', 'Error', 'modal');
                        invertOption = 0;
                    else
                    end
                    % No invertir
                    if invertOption == 0
                        switch app.signalChannelButton2
                            % Si esta ubicado en el canal 1 0 2 de la señal 2
                            case {1,2}
                                % REQUIERE PADDING
                                signal1 = app.dataSignal2_1;
                                signal2 = app.dataSignal2_2;
                                fs1 = app.arraySamplingFrequencyGraph2(1);
                                fs2 = app.arraySamplingFrequencyGraph2(2);
                                [signal1, fs1, signal2, fs2] = makePaddingFrecuency(app, signal1, fs1, signal2, fs2);
                                y = [signal1(2,:);signal2(2,:)];
                                y = y.';
                                % Comprobar que las dos señales tiene la misma Fs
                                sound(y, Fs);
                            % Si esta ubicado en el out 1 0 2 de la señal 2
                            case {3,4}
                                % REQUIERE PADDING
                                signal1 = app.dataSignal2_3;
                                signal2 = app.dataSignal2_4;
                                fs1 = app.arraySamplingFrequencyGraph2(3);
                                fs2 = app.arraySamplingFrequencyGraph2(4);
                                [signal1, fs1, signal2, fs2] = makePaddingFrecuency(app, signal1, fs1, signal2, fs2);
                                y = [signal1(2,:);signal2(2,:)];
                                y = y.';
                                % Comprobar que las dos señales tiene la misma Fs
                                sound(y, Fs);
                        end
                    % Invertir
                    elseif invertOption == 1
                        switch app.signalChannelButton2
                            % Si esta ubicado en el canal 1 0 2 de la señal 2
                            case {1,2}
                                % REQUIERE PADDING
                                signal1 = app.dataSignal2_1;
                                signal2 = app.dataSignal2_2;
                                fs1 = app.arraySamplingFrequencyGraph2(1);
                                fs2 = app.arraySamplingFrequencyGraph2(2);
                                [signal1, fs1, signal2, fs2] = makePaddingFrecuency(app, signal1, fs1, signal2, fs2);
                                y = [signal1(2,:);signal2(2,:)];
                                y = y.';
                                % Comprobar que las dos señales tiene la misma Fs
                                sound(fliplr(y), Fs);
                            % Si esta ubicado en el out 1 0 2 de la señal 2
                            case {3,4}
                                % REQUIERE PADDING
                                signal1 = app.dataSignal2_3;
                                signal2 = app.dataSignal2_4;
                                fs1 = app.arraySamplingFrequencyGraph2(3);
                                fs2 = app.arraySamplingFrequencyGraph2(4);
                                [signal1, fs1, signal2, fs2] = makePaddingFrecuency(app, signal1, fs1, signal2, fs2);
                                y = [signal1(2,:);signal2(2,:)];
                                y = y.';
                                % Comprobar que las dos señales tiene la misma Fs
                                sound(fliplr(y), Fs);
                        end
                    end
                else
                end
            % Sino es estereo
            else
                errordlg('The signal is not stereo, please try again when it is.', 'Error', 'modal');
            end
        end

        % Button pushed function: ConverttostereoButton
        function ConverttostereoButtonPushed(app, event)
            select = questdlg('Select the signal to change', ' Select signal', ...
                'Signal 1', 'Signal 2', 'Cancel', ...
                'Signal 1');
            switch select
                case 'Signal 1'
                    app.MonoToStereo = ConvertMonoToStereo(app, 0);
                case 'Signal 2'
                    app.MonoToStereo = ConvertMonoToStereo(app, 1);
                case 'Cancel'
            end
        end

        % Button pushed function: ConverttomonoButton
        function ConverttomonoButtonPushed(app, event)
            select = questdlg('Select the signal to change', ' Select signal', ...
                'Signal 1', 'Signal 2', 'Cancel', ...
                'Signal 1');
            switch select
                case 'Signal 1'
                    app.StereoToMono = ConvertStereoToMono(app, 0);
                case 'Signal 2'
                    app.StereoToMono = ConvertStereoToMono(app, 1);
                case 'Cancel'
            end
        end

        % Button pushed function: FIRButton
        function FIRButtonPushed(app, event)
            switch app.signalChannelButton1
                case 1
                    if(~isempty(app.dataSignal1_1))
                        if(isempty(app.dataSignalHn1_1))
                            convolutionBlocks(app, app.dataSignal1_1, app.signalChannelButton1, [], 0, 1);
                        else
                            convolutionBlocks(app, app.dataSignal1_1, app.signalChannelButton1, app.dataSignalHn1_1, app.zero(1), 1);
                        end
                    else
                        msgbox('No signal has been entered on the channel 1.', 'Error', 'error');
                        return;
                    end
                case 2
                    if(~isempty(app.dataSignal1_2))
                        if(isempty(app.dataSignalHn1_2))
                            convolutionBlocks(app, app.dataSignal1_2, app.signalChannelButton1, [], 0, 1);
                        else
                            convolutionBlocks(app, app.dataSignal1_2, app.signalChannelButton1, app.dataSignalHn1_2, app.zero(2), 1);
                        end
                    else
                        msgbox('No signal has been entered on the channel 2.', 'Error', 'error');
                        return;
                    end
                case 3
                    if(~isempty(app.dataSignal1_3))
                        if(isempty(app.dataSignalHn1_1))
                            convolutionBlocks(app, app.dataSignal1_3, app.signalChannelButton1, [], 0, 1);
                        else
                            convolutionBlocks(app, app.dataSignal1_3, app.signalChannelButton1, app.dataSignalHn1_1, app.zero(1), 1);
                        end
                    else
                        msgbox('No signal has been entered on the out 1.', 'Error', 'error');
                        return;
                    end
                case 4
                    if(~isempty(app.dataSignal1_4))
                        if(isempty(app.dataSignalHn1_2))
                            convolutionBlocks(app, app.dataSignal1_4, app.signalChannelButton1, [], 0, 1);
                        else
                            convolutionBlocks(app, app.dataSignal1_4, app.signalChannelButton1, app.dataSignalHn1_2, app.zero(2), 1);
                        end
                    else
                        msgbox('No signal has been entered on the out 2.', 'Error', 'error');
                        return;
                    end
                case 5
                    if(~isempty(app.dataSignalResult1))
                        if(isempty(app.dataSignalHn1_1))
                            convolutionBlocks(app, app.dataSignalResult1, app.signalChannelButton1, [], 0, 1);
                        else
                            convolutionBlocks(app, app.dataSignalResult1, app.signalChannelButton1, app.dataSignalHn1_1, app.zero(1), 1);
                        end
                    else
                        msgbox('No signal has been entered on the Result.', 'Error', 'error');
                        return;
                    end  
            end
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            if(~isempty(app.dataAuxYnTemp1))
                if(app.typeFilter1 == 0)
                    if(mod(app.channelAux(1), 2) == 0)
                        graphHn(app, app.dataSignalHn1_2(2,:), app.dataSignalHn1_2(1,:), 'h1(n) by Convolution.', 1);
                        graphSignal1(app, app.dataAuxYnTemp1(2,:), app.dataAuxYnTemp1(1,:), 4, app.arraySamplingFrequencyGraph1(2));
                    else
                        graphHn(app, app.dataSignalHn1_1(2,:), app.dataSignalHn1_1(1,:), 'h1(n) by Convolution.', 1);
                        graphSignal1(app, app.dataAuxYnTemp1(2,:), app.dataAuxYnTemp1(1,:), 3, app.arraySamplingFrequencyGraph1(1));
                    end
                    delete(app.auxCloseConvolutionWindow1);
                    app.dataAuxYnTemp1 = [];
                    app.channelAux(1) = 0;
                else
                    % Aplicar IIR.
                end
            else
                msgbox('No filtering process has been carried out', 'Error', 'error');
                return;
            end
        end

        % Button pushed function: FIRButton_2
        function FIRButton_2Pushed(app, event)
            switch app.signalChannelButton2
                case 1
                    if(~isempty(app.dataSignal2_1))
                        if(isempty(app.dataSignalHn2_1))
                            convolutionBlocks(app, app.dataSignal2_1, app.signalChannelButton2, [], 0, 2);
                        else
                            convolutionBlocks(app, app.dataSignal2_1, app.signalChannelButton2, app.dataSignalHn2_1, app.zero(3), 2);
                        end
                    else
                        msgbox('No signal has been entered on the channel 1.', 'Error', 'error');
                        return;
                    end
                case 2
                    if(~isempty(app.dataSignal2_2))
                        if(isempty(app.dataSignalHn2_2))
                            convolutionBlocks(app, app.dataSignal2_2, app.signalChannelButton2, [], 0, 2);
                        else
                            convolutionBlocks(app, app.dataSignal2_2, app.signalChannelButton2, app.dataSignalHn2_2, app.zero(4), 2);
                        end
                    else
                        msgbox('No signal has been entered on the channel 2.', 'Error', 'error');
                        return;
                    end
                case 3
                    if(~isempty(app.dataSignal2_3))
                        if(isempty(app.dataSignalHn2_1))
                            convolutionBlocks(app, app.dataSignal2_3, app.signalChannelButton2, [], 0, 2);
                        else
                            convolutionBlocks(app, app.dataSignal2_3, app.signalChannelButton2, app.dataSignalHn2_1, app.zero(3), 2);
                        end
                    else
                        msgbox('No signal has been entered on the out 1.', 'Error', 'error');
                        return;
                    end
                case 4
                    if(~isempty(app.dataSignal2_4))
                        if(isempty(app.dataSignalHn2_2))
                            convolutionBlocks(app, app.dataSignal2_4, app.signalChannelButton2, [], 0, 2);
                        else
                            convolutionBlocks(app, app.dataSignal2_4, app.signalChannelButton2, app.dataSignalHn2_2, app.zero(4), 2);
                        end
                    else
                        msgbox('No signal has been entered on the out 2.', 'Error', 'error');
                        return;
                    end
                case 5
                    if(~isempty(app.dataSignalResult2))
                        if(isempty(app.dataSignalHn2_1))
                            convolutionBlocks(app, app.dataSignalResult2, app.signalChannelButton2, [], 0, 2);
                        else
                            convolutionBlocks(app, app.dataSignalResult2, app.signalChannelButton2, app.dataSignalHn2_1, app.zero(3), 2);
                        end
                    else
                        msgbox('No signal has been entered on the Result.', 'Error', 'error');
                        return;
                    end  
            end
        end

        % Button pushed function: ApplyButton_2
        function ApplyButton_2Pushed(app, event)
            if(~isempty(app.dataAuxYnTemp2))
                if(app.typeFilter2 == 0)
                    if(mod(app.channelAux(2), 2) == 0)
                        graphHn(app, app.dataSignalHn2_2(2,:), app.dataSignalHn2_2(1,:), 'h2(n) by Convolution.', 2);
                        graphSignal2(app, app.dataAuxYnTemp2(2,:), app.dataAuxYnTemp2(1,:), 4, app.arraySamplingFrequencyGraph2(2));
                    else
                        graphHn(app, app.dataSignalHn2_1(2,:), app.dataSignalHn2_1(1,:), 'h2(n) by Convolution.', 2);
                        graphSignal2(app, app.dataAuxYnTemp2(2,:), app.dataAuxYnTemp2(1,:), 3, app.arraySamplingFrequencyGraph2(1));
                    end
                    delete(app.auxCloseConvolutionWindow2);
                    app.dataAuxYnTemp2 = [];
                    app.channelAux(2) = 0;
                    delete(app.auxCloseConvolutionWindow2);
                else
                    %Aplicar IIR.
                end
            else
                msgbox('No filtering process has been carried out', 'Error', 'error');
                return;
            end
        end

        % Menu selected function: StandardizationMenu
        function StandardizationMenuSelected(app, event)
            normalizacion(app, app.signalChannelButton1, app.signalChannelButton2);
        end

        % Menu selected function: CreateMenu
        function CreateMenuSelected(app, event)
            app.CreateSig = CreateSignal(app);
        end

        % Menu selected function: Signal1Menu_2
        function Signal1Menu_2Selected(app, event)
            app.ViewFrec = ViewFrecuency(app, 1, app.dataSignal1_1, app.dataSignal1_2, ...
                app.dataSignal1_3, app.dataSignal1_4, app.arraySamplingFrequencyGraph1);
        end

        % Menu selected function: Signal2Menu_2
        function Signal2Menu_2Selected(app, event)
            app.ViewFrec = ViewFrecuency(app, 2, app.dataSignal2_1, app.dataSignal2_2, ...
                app.dataSignal2_3, app.dataSignal2_4, app.arraySamplingFrequencyGraph2);
        end

        % Menu selected function: ResultsandFiltersMenu
        function ResultsandFiltersMenuSelected(app, event)
            frec = [app.arraySamplingFrequencyGraph1(5),app.arraySamplingFrequencyGraph2(5)];
            app.ViewFrec = ViewFrecuency(app, 3, app.dataSignalResult1, app.dataSignalResult2, ...
                app.dataSignal1_3, app.dataSignal1_4, frec); %Pendiente
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SignalProcessorUIFigure = uifigure('Visible', 'off');
            app.SignalProcessorUIFigure.Name = 'Signal Processor';
            app.AddsignalMenu = uimenu(app.SignalProcessorUIFigure);
            app.AddsignalMenu.Text = 'Add signal';
            app.CreateMenu = uimenu(app.AddsignalMenu);
            app.CreateMenu.MenuSelectedFcn = createCallbackFcn(app, @CreateMenuSelected, true);
            app.CreateMenu.Text = 'Create';
            app.SyntheticMenu = uimenu(app.AddsignalMenu);
            app.SyntheticMenu.Text = 'Synthetic';
            app.SineMenu = uimenu(app.SyntheticMenu);
            app.SineMenu.MenuSelectedFcn = createCallbackFcn(app, @SineMenuSelected, true);
            app.SineMenu.Text = 'Sine';
            app.CosineMenu = uimenu(app.SyntheticMenu);
            app.CosineMenu.MenuSelectedFcn = createCallbackFcn(app, @CosineMenuSelected, true);
            app.CosineMenu.Text = 'Cosine';
            app.ChirpMenu = uimenu(app.SyntheticMenu);
            app.ChirpMenu.MenuSelectedFcn = createCallbackFcn(app, @ChirpMenuSelected, true);
            app.ChirpMenu.Text = 'Chirp';
            app.StepMenu = uimenu(app.SyntheticMenu);
            app.StepMenu.MenuSelectedFcn = createCallbackFcn(app, @StepMenuSelected, true);
            app.StepMenu.Text = 'Step';
            app.RampMenu = uimenu(app.SyntheticMenu);
            app.RampMenu.MenuSelectedFcn = createCallbackFcn(app, @RampMenuSelected, true);
            app.RampMenu.Text = 'Ramp';
            app.SawtoothMenu = uimenu(app.SyntheticMenu);
            app.SawtoothMenu.MenuSelectedFcn = createCallbackFcn(app, @SawtoothMenuSelected, true);
            app.SawtoothMenu.Text = 'Sawtooth';
            app.RecordaudioMenu = uimenu(app.AddsignalMenu);
            app.RecordaudioMenu.MenuSelectedFcn = createCallbackFcn(app, @RecordaudioMenuSelected, true);
            app.RecordaudioMenu.Text = 'Record audio';
            app.AudiofileMenu = uimenu(app.AddsignalMenu);
            app.AudiofileMenu.MenuSelectedFcn = createCallbackFcn(app, @AudiofileMenuSelected, true);
            app.AudiofileMenu.Text = 'Audio file';
            app.CaptureMenu = uimenu(app.AddsignalMenu);
            app.CaptureMenu.Text = 'Capture';
            app.SaveMenu = uimenu(app.SignalProcessorUIFigure);
            app.SaveMenu.Text = 'Save';
            app.Signal1Menu = uimenu(app.SaveMenu);
            app.Signal1Menu.Text = 'Signal 1';
            app.ChannelMenu = uimenu(app.Signal1Menu);
            app.ChannelMenu.Text = 'Channel';
            app.OutMenu = uimenu(app.Signal1Menu);
            app.OutMenu.Text = 'Out';
            app.Result1Menu = uimenu(app.Signal1Menu);
            app.Result1Menu.Text = 'Result 1';
            app.AllMenu = uimenu(app.Signal1Menu);
            app.AllMenu.Text = 'All';
            app.Signal2Menu = uimenu(app.SaveMenu);
            app.Signal2Menu.Text = 'Signal 2';
            app.ChannelMenu_2 = uimenu(app.Signal2Menu);
            app.ChannelMenu_2.Text = 'Channel';
            app.OutMenu_2 = uimenu(app.Signal2Menu);
            app.OutMenu_2.Text = 'Out';
            app.Result2Menu = uimenu(app.Signal2Menu);
            app.Result2Menu.Text = 'Result 2';
            app.AllMenu_2 = uimenu(app.Signal2Menu);
            app.AllMenu_2.Text = 'All';
            app.StandardizationMenu = uimenu(app.SignalProcessorUIFigure);
            app.StandardizationMenu.MenuSelectedFcn = createCallbackFcn(app, @StandardizationMenuSelected, true);
            app.StandardizationMenu.Text = 'Standardization';
            app.ViewMenu = uimenu(app.SignalProcessorUIFigure);
            app.ViewMenu.Text = 'View';
            app.FrecuencyMenu = uimenu(app.ViewMenu);
            app.FrecuencyMenu.Text = 'Frecuency';
            app.Signal1Menu_2 = uimenu(app.FrecuencyMenu);
            app.Signal1Menu_2.MenuSelectedFcn = createCallbackFcn(app, @Signal1Menu_2Selected, true);
            app.Signal1Menu_2.Text = 'Signal 1';
            app.Signal2Menu_2 = uimenu(app.FrecuencyMenu);
            app.Signal2Menu_2.MenuSelectedFcn = createCallbackFcn(app, @Signal2Menu_2Selected, true);
            app.Signal2Menu_2.Text = 'Signal 2';
            app.ResultsandFiltersMenu = uimenu(app.FrecuencyMenu);
            app.ResultsandFiltersMenu.MenuSelectedFcn = createCallbackFcn(app, @ResultsandFiltersMenuSelected, true);
            app.ResultsandFiltersMenu.Text = 'Results and Filters';
            app.GridLayout = uigridlayout(app.SignalProcessorUIFigure);
            app.UIAxes2 = uiaxes(app.GridLayout);
            title(app.UIAxes2, 'h')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'h')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.TabGroup = uitabgroup(app.GridLayout);
            app.Channel1Tab = uitab(app.TabGroup);
            app.Channel1Tab.Title = 'Channel 1';
            app.Channel1Tab.ButtonDownFcn = createCallbackFcn(app, @Channel1TabButtonDown, true);
            app.GridLayout8 = uigridlayout(app.Channel1Tab);
            app.UIAxes3 = uiaxes(app.GridLayout8);
            app.Channel2Tab = uitab(app.TabGroup);
            app.Channel2Tab.Title = 'Channel 2';
            app.Channel2Tab.ButtonDownFcn = createCallbackFcn(app, @Channel2TabButtonDown, true);
            app.GridLayout8_2 = uigridlayout(app.Channel2Tab);
            app.UIAxes3_2 = uiaxes(app.GridLayout8_2);
            app.Out1Tab = uitab(app.TabGroup);
            app.Out1Tab.Title = 'Out 1';
            app.Out1Tab.ButtonDownFcn = createCallbackFcn(app, @Out1TabButtonDown, true);
            app.GridLayout8_3 = uigridlayout(app.Out1Tab);
            app.UIAxes3_3 = uiaxes(app.GridLayout8_3);
            app.Out2Tab = uitab(app.TabGroup);
            app.Out2Tab.Title = 'Out 2';
            app.Out2Tab.ButtonDownFcn = createCallbackFcn(app, @Out2TabButtonDown, true);
            app.GridLayout8_4 = uigridlayout(app.Out2Tab);
            app.UIAxes3_4 = uiaxes(app.GridLayout8_4);
            app.Result1Tab = uitab(app.TabGroup);
            app.Result1Tab.Title = 'Result 1';
            app.Result1Tab.ButtonDownFcn = createCallbackFcn(app, @Result1TabButtonDown, true);
            app.GridLayout9 = uigridlayout(app.Result1Tab);
            app.UIAxes3_9 = uiaxes(app.GridLayout9);
            app.Panel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.Panel);
            app.AddButton = uibutton(app.GridLayout2, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.MultiplyButton = uibutton(app.GridLayout2, 'push');
            app.MultiplyButton.ButtonPushedFcn = createCallbackFcn(app, @MultiplyButtonPushed, true);
            app.MultiplyButton.Text = 'Multiply';
            app.Playbacksignal1Button = uibutton(app.GridLayout2, 'push');
            app.Playbacksignal1Button.ButtonPushedFcn = createCallbackFcn(app, @Playbacksignal1ButtonPushed, true);
            app.Playbacksignal1Button.Text = 'Play back signal 1';
            app.Playbacksignal2Button = uibutton(app.GridLayout2, 'push');
            app.Playbacksignal2Button.ButtonPushedFcn = createCallbackFcn(app, @Playbacksignal2ButtonPushed, true);
            app.Playbacksignal2Button.Text = 'Play back signal 2';
            app.ConverttostereoButton = uibutton(app.GridLayout2, 'push');
            app.ConverttostereoButton.ButtonPushedFcn = createCallbackFcn(app, @ConverttostereoButtonPushed, true);
            app.ConverttostereoButton.Text = 'Convert to stereo';
            app.ConverttomonoButton = uibutton(app.GridLayout2, 'push');
            app.ConverttomonoButton.ButtonPushedFcn = createCallbackFcn(app, @ConverttomonoButtonPushed, true);
            app.ConverttomonoButton.Text = 'Convert to mono';
            app.Panel_2 = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.Panel_2);
            app.AddfilterPanel = uipanel(app.GridLayout3);
            app.AddfilterPanel.Title = 'Add filter';
            app.GridLayout6 = uigridlayout(app.AddfilterPanel);
            app.FIRButton = uibutton(app.GridLayout6, 'push');
            app.FIRButton.ButtonPushedFcn = createCallbackFcn(app, @FIRButtonPushed, true);
            app.FIRButton.Text = 'FIR';
            app.IIRButton = uibutton(app.GridLayout6, 'push');
            app.IIRButton.Text = 'IIR';
            app.ApplyButton = uibutton(app.GridLayout6, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.PlaychannelButton = uibutton(app.GridLayout3, 'push');
            app.PlaychannelButton.ButtonPushedFcn = createCallbackFcn(app, @PlaychannelButtonPushed, true);
            app.PlaychannelButton.Text = 'Play channel';
            app.ShiftButton = uibutton(app.GridLayout3, 'push');
            app.ShiftButton.Text = 'Shift';
            app.Panel_6 = uipanel(app.GridLayout3);
            app.GridLayout11 = uigridlayout(app.Panel_6);
            app.MONOLabel = uilabel(app.GridLayout11);
            app.MONOLabel.Text = 'MONO';
            app.Panel_5 = uipanel(app.GridLayout);
            app.GridLayout5 = uigridlayout(app.Panel_5);
            app.Knob = uiknob(app.GridLayout5, 'continuous');
            app.Knob.Limits = [-20 20];
            app.Panel_4 = uipanel(app.GridLayout);
            app.GridLayout4 = uigridlayout(app.Panel_4);
            app.AddfilterPanel_2 = uipanel(app.GridLayout4);
            app.AddfilterPanel_2.Title = 'Add filter';
            app.GridLayout6_2 = uigridlayout(app.AddfilterPanel_2);
            app.FIRButton_2 = uibutton(app.GridLayout6_2, 'push');
            app.FIRButton_2.ButtonPushedFcn = createCallbackFcn(app, @FIRButton_2Pushed, true);
            app.FIRButton_2.Text = 'FIR';
            app.IIRButton_2 = uibutton(app.GridLayout6_2, 'push');
            app.IIRButton_2.Text = 'IIR';
            app.ApplyButton_2 = uibutton(app.GridLayout6_2, 'push');
            app.ApplyButton_2.ButtonPushedFcn = createCallbackFcn(app, @ApplyButton_2Pushed, true);
            app.ApplyButton_2.Text = 'Apply';
            app.PlaychannelButton_2 = uibutton(app.GridLayout4, 'push');
            app.PlaychannelButton_2.ButtonPushedFcn = createCallbackFcn(app, @PlaychannelButton_2Pushed, true);
            app.PlaychannelButton_2.Text = 'Play channel';
            app.ShitfButton = uibutton(app.GridLayout4, 'push');
            app.ShitfButton.Text = 'Shitf';
            app.Panel_7 = uipanel(app.GridLayout4);
            app.GridLayout12 = uigridlayout(app.Panel_7);
            app.MONOLabel_2 = uilabel(app.GridLayout12);
            app.MONOLabel_2.Text = 'MONO';
            app.Panel_3 = uipanel(app.GridLayout);
            app.GridLayout7 = uigridlayout(app.Panel_3);
            app.Switch = uiswitch(app.GridLayout7, 'slider');
            app.Switch.Items = {'Constant', 'Linear'};
            app.Switch.Value = 'Constant';
            app.SetvolumeEditFieldLabel = uilabel(app.GridLayout7);
            app.SetvolumeEditFieldLabel.Text = 'Set volume';
            app.SetvolumeEditField = uieditfield(app.GridLayout7, 'text');
            app.ApplyButton_3 = uibutton(app.GridLayout7, 'push');
            app.ApplyButton_3.Text = 'Apply';
            app.Signal1CheckBox = uicheckbox(app.GridLayout7);
            app.Signal1CheckBox.Text = 'Signal 1';
            app.Signal2CheckBox = uicheckbox(app.GridLayout7);
            app.Signal2CheckBox.Text = 'Signal2';
            app.TabGroup_2 = uitabgroup(app.GridLayout);
            app.Channel1Tab_2 = uitab(app.TabGroup_2);
            app.Channel1Tab_2.Title = 'Channel 1';
            app.Channel1Tab_2.ButtonDownFcn = createCallbackFcn(app, @Channel1Tab_2ButtonDown, true);
            app.GridLayout8_5 = uigridlayout(app.Channel1Tab_2);
            app.UIAxes3_5 = uiaxes(app.GridLayout8_5);
            app.Channel2Tab_2 = uitab(app.TabGroup_2);
            app.Channel2Tab_2.Title = 'Channel 2';
            app.Channel2Tab_2.ButtonDownFcn = createCallbackFcn(app, @Channel2Tab_2ButtonDown, true);
            app.GridLayout8_6 = uigridlayout(app.Channel2Tab_2);
            app.UIAxes3_6 = uiaxes(app.GridLayout8_6);
            app.Out1Tab_2 = uitab(app.TabGroup_2);
            app.Out1Tab_2.Title = 'Out 1';
            app.Out1Tab_2.ButtonDownFcn = createCallbackFcn(app, @Out1Tab_2ButtonDown, true);
            app.GridLayout8_7 = uigridlayout(app.Out1Tab_2);
            app.UIAxes3_7 = uiaxes(app.GridLayout8_7);
            app.Out2Tab_2 = uitab(app.TabGroup_2);
            app.Out2Tab_2.Title = 'Out 2';
            app.Out2Tab_2.ButtonDownFcn = createCallbackFcn(app, @Out2Tab_2ButtonDown, true);
            app.GridLayout8_8 = uigridlayout(app.Out2Tab_2);
            app.UIAxes3_8 = uiaxes(app.GridLayout8_8);
            app.Result2Tab = uitab(app.TabGroup_2);
            app.Result2Tab.Title = 'Result 2';
            app.Result2Tab.ButtonDownFcn = createCallbackFcn(app, @Result2TabButtonDown, true);
            app.GridLayout10 = uigridlayout(app.Result2Tab);
            app.UIAxes3_10 = uiaxes(app.GridLayout10);
            % Show the figure after all components are created
            app.SignalProcessorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MainApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SignalProcessorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SignalProcessorUIFigure)
        end
    end
end