classdef OperationSignals < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SelectUIFigure   matlab.ui.Figure
        Signal2DropDown  matlab.ui.control.DropDown
        Signal2Label     matlab.ui.control.Label
        Signal1DropDown  matlab.ui.control.DropDown
        Signal1Label     matlab.ui.control.Label
        Button           matlab.ui.control.Button
    end

    
    properties (Access = public)
        CallingApp
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, operation)
            movegui(app.SelectUIFigure, 'center');
            app.CallingApp = caller;
            switch operation
                case 0
                    app.Button.Text = "Add";
                case 1
                    app.Button.Text = "Multiply";

            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            select = questdlg('Select the channel to save', ' Select Channel', ...
                'Result 1', 'Result 2', 'Cancel', ...
                'Result 1');
            switch app.Button.Text
                case 'Add'
                    opertationSignal(app.CallingApp, 0, select, app.Signal1DropDown.Value, app.Signal2DropDown.Value);
                case 'Multiply'
                    opertationSignal(app.CallingApp, 1, select, app.Signal1DropDown.Value, app.Signal2DropDown.Value);
            end
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SelectUIFigure = uifigure('Visible', 'off');
            app.SelectUIFigure.Name = 'Select';
            app.Button = uibutton(app.SelectUIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '';
            app.Signal1Label = uilabel(app.SelectUIFigure);
            app.Signal1Label.Text = 'Signal 1';
            app.Signal1DropDown = uidropdown(app.SelectUIFigure);
            app.Signal1DropDown.Items = {'Channel 1', 'Channel 2', 'Out 1', 'Out 2'};
            app.Signal1DropDown.Value = 'Channel 1';
            app.Signal2Label = uilabel(app.SelectUIFigure);
            app.Signal2Label.Text = 'Signal 2';
            app.Signal2DropDown = uidropdown(app.SelectUIFigure);
            app.Signal2DropDown.Items = {'Channel 1', 'Channel 2', 'Out 1', 'Out 2'};
            app.Signal2DropDown.Value = 'Channel 1';
            % Show the figure after all components are created
            app.SelectUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OperationSignals(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SelectUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SelectUIFigure)
        end
    end
end